classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SepbyEditField                  matlab.ui.control.NumericEditField
        SepbyLabel                      matlab.ui.control.Label
        SynchronizationPanel            matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        StartupTab                      matlab.ui.container.Tab
        PFIchannelLabel                 matlab.ui.control.Label
        ClockingTab                     matlab.ui.container.Tab
        PFIchannelLabel_2               matlab.ui.control.Label
        SyncDAQsviaclockfromamasterDAQLabel  matlab.ui.control.Label
        DoClockingCheckBox              matlab.ui.control.CheckBox
        DAQsListBox                     matlab.ui.control.ListBox
        DAQsLabel                       matlab.ui.control.Label
        ArmDataAcquisitionPanel         matlab.ui.container.Panel
        ArmDataAcquisitionSwitch        matlab.ui.control.Switch
        TriggerControlsPanel            matlab.ui.container.Panel
        TrigModeSwitch                  matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        ExtTrigTab                      matlab.ui.container.Tab
        RiseOrFallSwitch                matlab.ui.control.Switch
        VoltagethresholdEditField       matlab.ui.control.NumericEditField
        VoltagethresholdEditFieldLabel  matlab.ui.control.Label
        SamplesposttriggerEditField     matlab.ui.control.NumericEditField
        SamplesposttriggerEditFieldLabel  matlab.ui.control.Label
        ClickTrigTab                    matlab.ui.container.Tab
        TriggerButton                   matlab.ui.control.StateButton
        RateTimeControlsPanel           matlab.ui.container.Panel
        ReadRateHzEditField             matlab.ui.control.NumericEditField
        ReadRateHzEditFieldLabel        matlab.ui.control.Label
        RecordTimesEditField            matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel       matlab.ui.control.Label
        PlotRateHzEditField             matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel        matlab.ui.control.Label
        DAQRateHzEditField              matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel         matlab.ui.control.Label
        FileSavingParametersPanel       matlab.ui.container.Panel
        FolderNote                      matlab.ui.control.Label
        FolderButton                    matlab.ui.control.Button
        ProjectFolderEditField          matlab.ui.control.EditField
        ProjectFolderEditFieldLabel     matlab.ui.control.Label
        FilenameoptionalEditField       matlab.ui.control.EditField
        FilenameoptionalEditFieldLabel  matlab.ui.control.Label
        mainPlot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dn      % Names of recording DAQs (used to name fields for everything)
        s       % DAQ session 
        data    % Acquired data
        name    % Filenames and folders
        channels% Input channel info
        sync    % Synchronization controls and info
        rate    % Relevant information for timing/rates
        plt     % Relevant objects for plotting (plotTimer, colors, etc)
        channelPanels
        fieldsToSave = {'RecordTimesEditField', 'PlotRateHzEditField', ...
                'DAQRateHzEditField', 'ReadRateHzEditField', 'ProjectFolderEditField', ...
                'FilenameoptionalEditField', 'SepbyEditField', ...
                'DAQsListBox', 'DoClockingCheckBox', ...
                'TrigModeSwitch', 'RiseOrFallSwitch', 'VoltagethresholdEditField', 'SamplesposttriggerEditField'};
        perChannelFieldsToSave = {'chanOn_', 'chanDisplay_', 'chanName_', 'toggleButton_'};
        syncFieldsToSave = {'PFInum', 'clockPFInum'}
        recordingDisableFields = {'ProjectFolderEditField', 'FolderButton', ...
            'FilenameoptionalEditField', 'DAQRateHzEditField', 'PlotRateHzEditField', ...
            'ReadRateHzEditField', 'RecordTimesEditField', 'DAQsListBox', 'DoClockingCheckBox'};
        recordingDisableChannelFields = {'DAQNameField', 'terminalConfig', 'NumberofChannelsEditField', 'TrigButtonGroup'};
        recordingDisableSyncFields = {'PFInum', 'clockPFInum'};
        % TODO: Put Sync under channels to vastly simplify enable/disable
        % and load/save. Shorten channels to something 4 letter
    end

    methods (Access = private)

        % TODO: Combine GrabDataRise and GrabDataFall to single function
        % Function callback for when DAQ data available
        % Case: look for analog RISING trigger on trigger channel
        function GrabDataRise(app,obj,event)
            dq = obj.UserData;
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            newlen = size(newdata, 1);
            app.data.(dq).time = [app.data.(dq).time(newlen+1:end); timestamps];
            app.data.(dq).data = [app.data.(dq).data(newlen+1:end, :); newdata];
            % Check for trigger rise
            trigch = str2double(app.channels.(dq).TrigButtonGroup.SelectedObject.Tag);
            trigind = trigch == app.channels.(dq).active;
            if any(newdata(:, trigind) > app.VoltagethresholdEditField.Value)
                stop(app.s.(dq))
                alldone = false(1, length(app.dn));
                for i = 1:length(app.dn)
                    alldone(i) = ~app.s.(app.dn{i}).IsRunning;
                end
                if all(alldone)
                    stopRecordingReset(app)
                end
            end
        end

        % Function callback for when DAQ data available
        % Case: look for analog FALLING trigger on trigger channel
        function GrabDataFall(app,obj,event)
            dq = obj.UserData;
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            newlen = size(newdata, 1);
            app.data.(dq).time = [app.data.(dq).time(newlen+1:end); timestamps];
            app.data.(dq).data = [app.data.(dq).data(newlen+1:end, :); newdata];
            % Check for trigger fall
            trigch = str2double(app.channels.(dq).TrigButtonGroup.SelectedObject.Tag);
            trigind = trigch == app.channels.(dq).active;
            if any(info.Data(:, trigind) < app.VoltagethresholdEditField.Value)
                stop(app.s.(dq))
                alldone = false(1, length(app.dn));
                for i = 1:length(app.dn)
                    alldone(i) = ~app.s.(app.dn{i}).IsRunning;
                end
                if all(alldone)
                    stopRecordingReset(app)
                end
            end
        end

        % Function callback for when DAQ data available
        % Case: Trigger button controlled, no analog trigger searching
        function GrabData(app,obj,event)
            dq = obj.UserData;
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            shiftpos = size(newdata, 1);
            app.data.(dq).time = circshift(app.data.(dq).time, -shiftpos);
            app.data.(dq).data = circshift(app.data.(dq).data, -shiftpos);
            app.data.(dq).time(end-shiftpos+1:end,:) = timestamps;
            app.data.(dq).data(end-shiftpos+1:end,:) = newdata;
        end
        
        % Function callback repeatedly called by plot update timer
        function plotTimerUpdateFcn(app,src,event)
            for dq = app.DAQsListBox.Value
                i = dq{1};
                offset = app.SepbyEditField.Value;
                ind = 1;
                for j = app.channels.(i).active
                    % If channel set to plot
                    if app.channels.(i).(['chanDisplay_',num2str(j)]).Value
                        set(app.channels.(i).(['line_',num2str(j)]), ...
                            'XData', app.data.fakeTime(1:length(app.data.(i).data)), ...
                            'YData', app.data.(i).data(:, ind) + (ind-1)*offset);
                    else
                        set(app.channels.(i).(['line_',num2str(j)]), 'XData', nan, 'YData', nan);
                    end
                    ind = ind + 1;
                end
                drawnow limitrate
            end
        end
    
        % TODO: Clean this up, right now these functions aren't
        % consistently used. Display state not used at all 
        function getChannelActiveState(app, daq)
            app.channels.(daq).active = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).active(i) = strcmp(app.channels.(daq).(['chanOn_',num2str(i)]).Value, 'On');
            end
            app.channels.(daq).active = find(app.channels.(daq).active);
        end
        function getChannelDisplayState(app, daq)
            app.channels.(daq).display = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).display(i) = app.channels.(daq).(['chanDisplay_',num2str(i)]).Value;
            end
        end

        function createChannelPanel(app, index, numInit)
            % Create panel 
            app.channelPanels.(app.dn{index}) = uipanel(app.UIFigure, ...
                'Title', [app.dn{index}, ' channels'], ...
                'Position', [45 + (215 + 10)*(index-1), 0, 215, 400], ...
                'Scrollable', 'on', ...
                'Tag', num2str(index));
            % Create DAQ name field
            app.channels.(app.dn{index}).DAQNameField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'text', ...
                'Position', [0,360,80,22], ...
                'Value', app.dn{index}, ...
                'ValueChangedFcn', createCallbackFcn(app, @DAQNameValueChangedFcn, true), ...
                'Tooltip', {'Name of this DAQ','Data will be saved under file with this name'});
            % Create Terminal config selection field
            app.channels.(app.dn{index}).terminalConfig = uidropdown(app.channelPanels.(app.dn{index}), ...
                'Items', {'Differential', 'SingleEnded', 'SingleEndedNonReferenced', 'PseudoDifferential'}, ...
                'Value', 'Differential', ...
                'Position', [100, 360, 100, 22]);
            % Create "Number of channels" numeric field
            app.channels.(app.dn{index}).NumberofChannelsEditField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'numeric', ...
                'Position', [135,335,31,22], ...
                'Value', numInit, ...
                'ValueChangedFcn', createCallbackFcn(app, @ChangeNumChannelsFcn, true));
            % Create trigger selection button group
            height = 25*numInit + 10 + 2*app.channels.trigOffset;
            app.channels.(app.dn{index}).TrigButtonGroup = uibuttongroup(app.channelPanels.(app.dn{index}), ...
                'Position', [177, 400-height-54, 25, height], 'Title', 'Trig', 'FontSize', 10, ...
                'AutoResizeChildren', 'off');
            % Create global check or uncheck all box
            app.channels.(app.dn{index}).globalCheck = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 320, 25, 25], ...
                    'Value', 1, 'Text', '', ...
                    'ValueChangedFcn', createCallbackFcn(app, @CheckBoxValueChanged, true), ...
                    'Tooltip', 'Check or uncheck display status for all channels on this DAQ');
            % Create each channel
            for i = 1:numInit
                app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                    'Items', {'Off', 'On'}, 'Value', 'On', ...
                    'Position', [25, 308 - 25*(i-1), 23, 10], ...
                    'ValueChangedFcn', createCallbackFcn(app, @channelEnableCallbackFcn, true), ...
                    'Tag', num2str(i));
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 300 - 25*(i-1), 25, 25], ...
                    'Value', 1, 'Text', '', 'Tag', num2str(i), ...
                    'ValueChangedFcn', createCallbackFcn(app, @chanDisplayValueChangedFcn, true));
                app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                    'text', 'Value', ['ch',num2str(i)], ...
                    'Position', [102, 300 - 25*(i-1), 69, 20]);
                app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(numInit-i), 16, 16], 'Text', '', 'Tag', num2str(i));
            end
            % Create labels
            app.channels.(app.dn{index}).NumberofChannelsEditFieldLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Number of Channels:', 'Position', [9,337,119,22]);
            app.channels.(app.dn{index}).EnableLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Enable', 'Position', [22,318,42,22], 'FontSize', 10);
            app.channels.(app.dn{index}).ChannelNameLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Channel Name', 'Position', [100,318,85,22], 'FontSize', 10);
            % Note how many channels
            app.channels.(app.dn{index}).howMany = numInit;

            % Alter sync controls
            app.sync.(app.dn{index}).MasterButton = uitogglebutton(app.sync.MasterButtonGroup, ...
                'Position', [0, 155 - 25*(index-1), 50, 22], 'Text', app.dn{index}, 'Tag', app.dn{index});
            app.sync.(app.dn{index}).PFInum = uieditfield(app.StartupTab, ...
                'numeric', 'Position', [66, 155 - 25*(index-1), 72, 22], 'Value', 0);
            app.sync.(app.dn{index}).clockPFInum = uieditfield(app.ClockingTab, ...
                'numeric', 'Position', [66, 115 - 25*(index-1), 72, 22], 'Value', 1);
        end


        % Function to manually move trigger select buttons vertically 
        function resizeTrigButtons(app, panel, ncurrent, ntotal)
            for i = 1:ncurrent
                app.channels.(panel).(['toggleButton_',num2str(i)]).Position(2) = app.channels.trigOffset+25*(ntotal-i);
            end
        end


        % Function to start up DAQ and plot processes, lock in app state for recording
        function startRecording(app)
            % Reset axes and DAQs
            daqreset;
            cla(app.mainPlot)
            % Declare Rates 
            app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
            app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
            app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
            app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
            app.rate.read = round(app.rate.DAQ / app.ReadRateHzEditField.Value); %(samples)
            % Change looks of data acquisition switch
            app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
            app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
            % Make folder in chosen directory for today's date (if not already there)
            app.name.projFolder = app.ProjectFolderEditField.Value;
            app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
            if ~exist(app.name.path, 'dir')
                mkdir(app.name.projFolder, datestr(now,'yyyy_mm_dd'))
            end
            % Get which DAQ is clock and start trigger master
            masterDAQ = app.sync.MasterButtonGroup.SelectedObject.Tag;
            app.name.legendNames = {};
            % Loop over active DAQs
            for dq = app.DAQsListBox.Value
                i = dq{1};
                % Get which channels are active and should display
                getChannelActiveState(app, i)
                getChannelDisplayState(app, i)
                % Initialize filename of this test
                % TODO: Make smarter, so editfield can display current
                % filename without getting confused and in the next run
                % adding more shit to it
                if ~isempty(app.FilenameoptionalEditField.Value)
                    app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', app.FilenameoptionalEditField.Value, '.mat'];
                else
                    app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', char(datetime('now', 'format', 'yyyy_MM_dd')), '.mat'];
                end
                % Increment filename with numbers if same file already exists
                if exist(fullfile(app.name.path, app.name.(i).filename), 'file')
                    daqname = app.channels.(i).DAQNameField.Value;
                    % If no 3-digit number on end of file, add number
                    if isnan(str2double(app.name.(i).filename(end-6:end-4)))
                        app.name.(i).filename = [app.name.(i).filename(1:end-4), '_001', app.name.(i).filename(end-3:end)];
                        app.FilenameoptionalEditField.Value = app.name.(i).filename(length(daqname)+2:end-4);
                    % Otherwise grab and increment number
                    else
                        number = str2double(app.name.(i).filename(end-6:end-4));
                        app.name.(i).filename = [app.name.(i).filename(1:end-7), num2str(number+1,'%03.f'), app.name.(i).filename(end-3:end)];
                        app.FilenameoptionalEditField.Value = app.name.(i).filename(length(daqname)+2:end-4);
                    end
                end
                % Preallocate data
                app.data.(i).time = nan(app.rate.buffer, 1);
                app.data.(i).data = nan(app.rate.buffer, length(app.channels.(i).active));
                % Create DAQ session
                app.s.(i) = daq('ni');
                app.s.(i).Rate = app.rate.DAQ;
                app.s.(i).ScansAvailableFcnCount = app.rate.read;
                app.s.(i).UserData = i;
                % Create channels
                % Shift channel numbers if differential to reflect actual channel numbering used by NI
                if contains(app.channels.(i).terminalConfig.Value, 'Differential')
                    actualChanNumbers = app.channels.(i).active - 1;
                    actualChanNumbers(actualChanNumbers > 7) = actualChanNumbers(actualChanNumbers > 7) + 8;
                else
                    actualChanNumbers = app.channels.(i).active - 1;
                end
                addinput(app.s.(i), i, actualChanNumbers, 'Voltage');
                % Loop over set up channels, set all to specified terminalConfig
                for chind = 1:length(app.s.(i).Channels)
                    app.s.(i).Channels(chind).TerminalConfig = app.channels.(i).terminalConfig.Value;
                end
                % Create input/output listeners 
                % trigger from one of the analog input channels
                if strcmpi(app.TrigModeSwitch.Value, 'ExtTrigTab') 
                    % (two grab data functions based on triggering type)
                    if strcmpi(app.RiseOrFallSwitch.Value, 'Rising')
                        app.s.(i).ScansAvailableFcn = @app.GrabDataRise;
                    else
                        app.s.(i).ScansAvailableFcn = @app.GrabDataFall;
                    end
                % Trigger from click button only
                else
                    app.s.(i).ScansAvailableFcn = @app.GrabData;
                end

                % If this DAQ is minion
                if ~strcmp(i, masterDAQ) % TODO: This might be weak/vulnerable way to do this
                    % NOTE: This setup assumes for triggering and clocking all DAQs are connected on the same PFI channel. 
                    % IE for triggering on PFI1, all DAQs have PFI1 connected
                    addtrigger(app.s.(i), "Digital", "StartTrigger", [masterDAQ, '/PFI', num2str(app.sync.(i).PFInum.Value)], [i, '/PFI', num2str(app.sync.(i).PFInum.Value)]);
                    if app.DoClockingCheckBox.Value
                        addClock(app.s.(i), "ScanClock", [masterDAQ, '/PFI', num2str(app.sync.(i).clockPFInum.Value)], [i, '/PFI', num2str(app.sync.(i).clockPFInum.Value)])
                    end
                % If this DAQ is master
%                 else
% %                     addtrigger(app.s.(i), "Digital", "StartTrigger", "External", [i, '/PFI', num2str(app.sync.(i).PFInum.Value)]);
%                     if app.DoClockingCheckBox.Value
%                         addClockConnection(app.s.(i), [i, '/PFI', num2str(app.sync.(i).clockPFInum.Value)], 'External', 'ScanClock');
%                     end
                end

                % Disable per-DAQ fields that shouldn't change during recording
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'Off';
                end
                for j = 1:length(app.recordingDisableChannelFields)
                    app.channels.(i).(app.recordingDisableChannelFields{j}).Enable = 'Off';
                end
                for j = 1:length(app.recordingDisableSyncFields)
                    app.sync.(i).(app.recordingDisableChannelFields{j}).Enable = 'Off';
                end
                % Plot setup
                % Remove lines from previous run (if there were any)
                chanFieldNames = fieldnames(app.channels.(i));
                if any(contains(chanFieldNames, 'line_'))
                    for lineHandle = chanFieldNames(contains(chanFieldNames, 'line_'))
                        delete(app.channels.(i).(lineHandle{1}))
                    end
                end
                % Setup new lines for each channel
                for j = 1:app.channels.(i).howMany
                    % If this channel set to plot
                    if strcmpi(app.channels.(i).(['chanOn_',num2str(j)]).Value, 'On')
                        % Create placeholder plot lines for each channel to update
                        app.channels.(i).(['line_',num2str(j)]) = line(app.mainPlot, nan, nan, 'color', app.plt.colors(j,:));
                        % Get name of this channel for legend
                        app.name.legendNames{end+1} = app.channels.(i).(['chanName_',num2str(j)]).Value;
                    end
                end
            end
            % Disable fields that shouldn't change during recording
            for i = 1:length(app.recordingDisableFields)
                app.(app.recordingDisableFields{i}).Enable = 'Off';
            end
            app.sync.MasterButtonGroup.Enable = 'Off';

            % Create plot legend
            legend(app.mainPlot, app.name.legendNames, 'location', 'northwest')
            % Create plot timer
            app.rate.plot = app.PlotRateHzEditField.Value;
            app.plt.plotTimer = timer('TimerFcn', {@app.plotTimerUpdateFcn},...
                'ExecutionMode', 'fixedRate', 'busymode', 'drop',...
                'StartDelay', 0.2, 'Period', 1/app.rate.plot, 'Name', 'timer-1');
            start(app.plt.plotTimer);
            % Time x-axis stand-in to make plotting more visually appealing
            app.data.fakeTime = linspace(0, app.rate.plotLength, app.rate.buffer);
            
            % If minion DAQs, start them up and wait for all to be ready for trigger
            if length(app.DAQsListBox.Value) > 1
                % Start minion DAQs
                for dq = app.DAQsListBox.Value(~contains(app.DAQsListBox.Value, masterDAQ))
                    i = dq{1};
                    start(app.s.(i), "Continuous");
                end
                % Pause to allow time for last minion to get ready
                while ~app.s.(i).IsWaitingForExternalTrigger
                    pause(0.1)
                end
            end
            % Start master DAQ
            start(app.s.(masterDAQ), "Continuous");
        end

        % Function to save data, restart DAQ timers+listeners, and reset app state for next recording
        function stopRecordingReset(app)
            for dq = app.DAQsListBox.Value
                i = dq{1};
                % Stop sessions, delete listeners
                stop(app.s.(i))
                % Get channel names to save
                app.name.(i).chanNames = cell(1, app.channels.(i).howMany + 1);
                app.name.(i).chanNames{1} = 'time';
                for j = 1:app.channels.(i).howMany
                    app.name.(i).chanNames{j+1} = app.channels.(i).(['chanName_',num2str(j)]).Value;
                end
                % TODO: Deal with different types of triggering
                % Write data to file
                % TODO: better way than this wasteful method
                savedata = struct();
                savedata.data = [app.data.(i).time, app.data.(i).data];
                savedata.channelNames = app.name.(i).chanNames;
                save(fullfile(app.name.path, app.name.(i).filename), '-struct', 'savedata', 'data', 'channelNames')
                % Re-enable fields 
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'On';
                end
                for j = 1:length(app.recordingDisableChannelFields)
                    app.channels.(i).(app.recordingDisableChannelFields{j}).Enable = 'On';
                end
                for j = 1:length(app.recordingDisableSyncFields)
                    app.sync.(i).(app.recordingDisableChannelFields{j}).Enable = 'On';
                end

            end
            % Stop plot timer
            if strcmp(get(app.plt.plotTimer,'Running'),'on')
                stop(app.plt.plotTimer)
            end
            % Reset GUI, re-enable everything
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            % Re-enable fields
            for i = 1:length(app.recordingDisableFields)
                app.(app.recordingDisableFields{i}).Enable = 'On';
            end
            app.sync.MasterButtonGroup.Enable = 'On';
        end

        function loadState(app, loadfile)
            if ~exist(loadfile, 'file')
                return
            elseif ~ismember('state', who('-file', loadfile))
                warning('Not a valid state file!')
                return
            end
            state = load(loadfile, 'state');
            state = state.state;
            % Load value of main fields
            for i = 1:length(app.fieldsToSave)
                app.(app.fieldsToSave{i}).Value = state.(app.fieldsToSave{i}).Value;
            end
            % Load acquisition enable state
            app.ArmDataAcquisitionSwitch.Enable = state.ArmDataAcquisitionSwitch.Enable;
            app.FolderNote.Visible = state.FolderNote.Visible;
            % Load values from channels fields, regenerate channel panels
            app.dn = state.dn;
            for i = state.channels.indices
                dq = app.dn{i};
                app.channels.(dq).howMany = state.channels.(dq).howMany;
                createChannelPanel(app, i, app.channels.(dq).howMany)
                % Update states for this panel
                for j = 1:app.channels.(dq).howMany
                    for k = app.perChannelFieldsToSave
                        app.channels.(dq).([k{1},num2str(j)]).Value = state.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                app.channels.(dq).DAQNameField.Value = state.channels.(dq).DAQNameField.Value;
                app.channels.(dq).terminalConfig.Value = state.channels.(dq).terminalConfig.Value;
                app.channels.(dq).TrigButtonGroup.Visible = state.channels.(dq).TrigButtonGroup.Visible;
                % Load sync field states
                app.sync.(dq).MasterButton.Value = state.sync.(dq).MasterButton.Value;
                app.sync.(dq).PFInum.Value = state.sync.(dq).PFInum.Value;
                app.sync.(dq).clockPFInum.Value = state.sync.(dq).clockPFInum.Value;
            end
        end
        function saveState(app, savefile)
            state = struct();
            % Save value of important fields
            for i = 1:length(app.fieldsToSave)
                state.(app.fieldsToSave{i}).Value = app.(app.fieldsToSave{i}).Value;
            end
            % Save whether acquisition was enabled or not
            state.ArmDataAcquisitionSwitch.Enable = app.ArmDataAcquisitionSwitch.Enable;
            state.FolderNote.Visible = app.FolderNote.Visible;
            % Save key values from channels to allow regenerating channel panels
            state.dn = app.dn;
            state.channels.indices = find(~cellfun(@isempty, app.dn));
            state.channels.indexAvailable = app.channels.indexAvailable;
            % Loop over active DAQs with channelPanels
            for i = state.channels.indices
                dq = app.dn{i};
                state.channels.(dq).howMany = app.channels.(dq).howMany;
                % Loop over channels in this panel, save states
                for j = 1:state.channels.(dq).howMany
                    for k = app.perChannelFieldsToSave
                        state.channels.(dq).([k{1},num2str(j)]).Value = app.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                state.channels.(dq).DAQNameField.Value = app.channels.(dq).DAQNameField.Value;
                state.channels.(dq).terminalConfig.Value = app.channels.(dq).terminalConfig.Value;
                state.channels.(dq).TrigButtonGroup.Visible = app.channels.(dq).TrigButtonGroup.Visible;
                % Save sync field states
                state.sync.(dq).MasterButton.Value = app.sync.(dq).MasterButton.Value;
                state.sync.(dq).PFInum.Value = app.sync.(dq).PFInum.Value;
                state.sync.(dq).clockPFInum.Value = app.sync.(dq).clockPFInum.Value;
            end
            % Save to file
            save(savefile,'state');
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up pre-defined constants held as sub-fields
            % Positioning constants
            app.channels.trigOffset = 8; %px
            % Plot colors (assuming nobody will want to acquire and plot more than 100 channels)
            app.plt.colors = lines(100);

            % Populate DAQs list box based on active and connected DAQs
            % If they have data acquisition toolbox installed
            if exist('daqlist', 'file') 
                daqreset % reset needed to update daqlist
                daqs = daqlist;
                if isempty(daqs)
                    warning(['No DAQs are turned on and connected\nWill run in demo mode, many features will not work'], 1)
                    app.DAQsListBox.Items = {'fakeDev1', 'fakeDev2', 'fakeDev3', 'fakeDev4'};
                else
                    app.DAQsListBox.Items = cellstr(daqs{:,2})';
                end
            % Otherwise spit warning, give fake DAQ names for demos/testing
            else
                warning(['Data acquisition toolbox likely not installed!\n' ...
                    'Will run in demo mode, many features will not work'], 1)
                app.DAQsListBox.Items = {'fakeDev1', 'fakeDev2', 'fakeDev3', 'fakeDev4'};
            end
            % Create sync controls
            app.sync.MasterButtonGroup =  uibuttongroup(app.StartupTab, ...
                'Position', [3, 0, 50, 200], 'Title', 'Master', 'AutoResizeChildren', 'off');
            % For the first connected+active DAQ, run channel panel creation
            app.DAQsListBox.Value = app.DAQsListBox.Items(1);
            app.dn = app.DAQsListBox.Items(1);
            createChannelPanel(app, 1, 10);
            app.channels.indexAvailable = [2];
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            folderName = uigetdir(pwd, 'Select directory for saving data');
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                % Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                startRecording(app)
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end

            % Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    stop(app.s.(i))
                end
                % Delete Listeners
%                 for dq = app.DAQsListBox.Value
%                     i = dq{1};
%                     delete(app.lh.(i))
%                 end
                % Stop timers
                if strcmp(get(app.plt.plotTimer,'Running'),'on')
                    stop(app.plt.plotTimer)
                end
            catch
            end
            
            delete(app)
        end

        % Value changed function: TrigModeSwitch
        function TrigModeChangedFcn(app, event)
            value = app.TrigModeSwitch.Value;
            app.TabGroup.SelectedTab = app.(value);
            if strcmp(value, 'ExtTrigTab')
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'on';
                end
            else
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'off';
                end
            end
        end

        % Callback function
        function ChangeNumChannelsFcn(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % If new # of channels less than current, remove some channels
            if value < app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                % TODO: Make actual references to channel panel dimensions
                % (rather than hard-coded 400 constant)
                % Loop over channels to remove
                for i = (value+1):app.channels.(app.dn{index}).howMany
                    delete(app.channels.(app.dn{index}).(['chanOn_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanName_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]))
                end
                resizeTrigButtons(app, app.dn{index}, value, value)
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
                
            % If new number more than current, add some channels
            elseif value > app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                resizeTrigButtons(app, app.dn{index}, app.channels.(app.dn{index}).howMany, value)
                % Loop over channels to create
                for i = (app.channels.(app.dn{index}).howMany+1):value
                    % TODO: Have one function to call in both situations for creating channels to reduce code duplication
                    app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                        'Items', {'Off', 'On'}, 'Value', 'On', ...
                        'Position', [25, 308 - 25*(i-1), 23, 10], ...
                        'ValueChangedFcn', createCallbackFcn(app, @channelEnableCallbackFcn, true), ...
                        'Tag', num2str(i));
                    app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                        'Position', [78, 300 - 25*(i-1), 25, 25], ...
                        'Value', 1, 'Text', '', 'Tag', num2str(i), ...
                        'ValueChangedFcn', createCallbackFcn(app, @chanDisplayValueChangedFcn, true));
                    app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                        'text', 'Value', ['ch',num2str(i)], ...
                        'Position', [102, 300 - 25*(i-1), 69, 20]);
                    app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(value-i), 16, 16], 'Text', '', 'Tag', num2str(i));
                end
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
            end
            % If same number of channels, do nothing
        end

        % Value changed function: DAQsListBox
        function NewDAQFcn(app, event)
            % Get removed DAQs, call remove channel function on them
            removedDAQs = event.PreviousValue(~contains(event.PreviousValue, event.Value));
            for i = 1:length(removedDAQs)
                % Delete
                delete(app.channelPanels.(removedDAQs{i}))
                delete(app.sync.(removedDAQs{i}).MasterButton)
                delete(app.sync.(removedDAQs{i}).PFInum)
                delete(app.sync.(removedDAQs{i}).clockPFInum)
                % clear out to allow new boxes to take this location
                index = find(strcmp(app.dn, removedDAQs(i)));
                app.dn{index} = '';
                app.channels.indexAvailable(end+1) = index;
            end
            
            % Get new DAQs, call create channel function on them
            newDAQs = event.Value(~contains(event.Value, event.PreviousValue));
            for i = 1:length(newDAQs)
                % If adding this channel panel to end
                if length(app.channels.indexAvailable) == 1
                    index = app.channels.indexAvailable(1);
                    app.channels.indexAvailable = app.channels.indexAvailable + 1;
                    app.dn{end+1} = newDAQs{i};
                % If putting in open middle slot
                else
                    sortAvailable = sort(app.channels.indexAvailable);
                    index = sortAvailable(1);
                    app.channels.indexAvailable(app.channels.indexAvailable==index) = [];
                    app.dn{index} = newDAQs{i};
                end
                % Create panel
                createChannelPanel(app, index, 10);
            end

        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Loop over number of channels, check or uncheck all
            for i = 1:app.channels.(app.dn{index}).howMany
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]).Value = value;
            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuFcn(app, event)
            [file, path] = uigetfile('*.mat', 'Select valid file to load app state from');
            loadState(app, fullfile(path, file))
        end

        % Menu selected function: SaveMenu
        function SaveMenuFcn(app, event)
            [file, path] = uiputfile('*.mat', 'Select location and name to save app state file under');
            saveState(app, fullfile(path, file))
        end

        % Callback function
        function DAQNameValueChangedFcn(app, event)
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Change channel panels title
            app.channelPanels.(app.dn{index}).Title = [event.Value, ' channels'];
            % Change master button text
            app.sync.(app.dn{index}).MasterButton.Text = event.Value;
        end

        % Value changed function: DoClockingCheckBox
        function DoClockingValueChangedFcn(app, event)
            value = app.DoClockingCheckBox.Value;
            if value
                enableState = 'On';
            else
                enableState = 'Off';
            end
            for dq = app.DAQsListBox.Value
                i = dq{1};
                app.sync.(i).PFInum.Enable = enableState;
                app.sync.(i).clockPFInum.Enable = enableState;
            end
        end

        % Callback function
        function channelEnableCallbackFcn(app, event)
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % get which channel index this is
            chind = str2double(event.Source.Tag);
            % If switched to off
            if strcmpi(event.Source.Value, 'Off')
                % Set display checkbox to off, disable
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Value = 0;
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Enable = 'off';
            else
                % Set display checkbox to on, enable
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Value = 1;
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Enable = 'on';
            end
        end

        % Callback function
        function chanDisplayValueChangedFcn(app, event)
            % If not currently running, update plot
            if isempty(app.ArmDataAcquisitionSwitch.Value)
                % Check Tag to know which channel panel and channel this is
                index = str2double(event.Source.Parent.Tag);
                chind = str2double(event.Source.Tag);
                % Proceed only if channel enabled
                if strcmpi(app.channels.(app.dn{index}).(['chanOn_',num2str(chind)]).Value, 'On')
                    if event.Value && any(chind == app.channels.(app.dn{index}).active)
                        ind = find(chind == app.channels.(app.dn{index}).active);
                        offset = app.SepbyEditField.Value;
                        set(app.channels.(app.dn{index}).(['line_',num2str(chind)]), ...
                            'XData', app.data.fakeTime(1:length(app.data.(app.dn{index}).data)), ...
                            'YData', app.data.(app.dn{index}).data(:, ind) + (ind-1)*offset);
                    else
                        set(app.channels.(app.dn{index}).(['line_',num2str(chind)]), 'XData', nan, 'YData', nan);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuFcn, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuFcn, true);
            app.SaveMenu.Text = 'Save';
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameoptionalEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameoptionalEditFieldLabel.Text = 'Filename (optional)';
            app.FilenameoptionalEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ReadRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.ReadRateHzEditFieldLabel.Text = 'Read Rate (Hz)';
            app.ReadRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.ReadRateHzEditField.Value = 5;
            app.TriggerControlsPanel = uipanel(app.UIFigure);
            app.TriggerControlsPanel.Title = 'Trigger Controls';
            app.TabGroup = uitabgroup(app.TriggerControlsPanel);
            app.ExtTrigTab = uitab(app.TabGroup);
            app.ExtTrigTab.Title = 'Ext Trig';
            app.SamplesposttriggerEditFieldLabel = uilabel(app.ExtTrigTab);
            app.SamplesposttriggerEditFieldLabel.Text = 'Samples post-trigger';
            app.SamplesposttriggerEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.SamplesposttriggerEditField.Value = 15;
            app.VoltagethresholdEditFieldLabel = uilabel(app.ExtTrigTab);
            app.VoltagethresholdEditFieldLabel.Text = 'Voltage threshold';
            app.VoltagethresholdEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.VoltagethresholdEditField.Value = 2;
            app.RiseOrFallSwitch = uiswitch(app.ExtTrigTab, 'slider');
            app.RiseOrFallSwitch.Items = {'Rising', 'Falling'};
            app.RiseOrFallSwitch.Value = 'Rising';
            app.ClickTrigTab = uitab(app.TabGroup);
            app.ClickTrigTab.Title = 'Click Trig';
            app.TriggerButton = uibutton(app.ClickTrigTab, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TrigModeSwitch = uiswitch(app.TriggerControlsPanel, 'slider');
            app.TrigModeSwitch.Items = {'External', 'Click Button'};
            app.TrigModeSwitch.ItemsData = {'ExtTrigTab', 'ClickTrigTab'};
            app.TrigModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrigModeChangedFcn, true);
            app.TrigModeSwitch.Value = 'ExtTrigTab';
            app.ArmDataAcquisitionPanel = uipanel(app.UIFigure);
            app.ArmDataAcquisitionPanel.Title = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.ArmDataAcquisitionPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.DAQsLabel = uilabel(app.UIFigure);
            app.DAQsLabel.Text = 'DAQs:';
            app.DAQsListBox = uilistbox(app.UIFigure);
            app.DAQsListBox.Items = {};
            app.DAQsListBox.Multiselect = 'on';
            app.DAQsListBox.ValueChangedFcn = createCallbackFcn(app, @NewDAQFcn, true);
            app.DAQsListBox.Value = {};
            app.SynchronizationPanel = uipanel(app.UIFigure);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.TabGroup2 = uitabgroup(app.SynchronizationPanel);
            app.StartupTab = uitab(app.TabGroup2);
            app.StartupTab.Title = 'Startup';
            app.PFIchannelLabel = uilabel(app.StartupTab);
            app.PFIchannelLabel.Text = 'PFI channel #';
            app.ClockingTab = uitab(app.TabGroup2);
            app.ClockingTab.Title = 'Clocking';
            app.DoClockingCheckBox = uicheckbox(app.ClockingTab);
            app.DoClockingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoClockingValueChangedFcn, true);
            app.DoClockingCheckBox.Text = '';
            app.DoClockingCheckBox.Value = true;
            app.SyncDAQsviaclockfromamasterDAQLabel = uilabel(app.ClockingTab);
            app.SyncDAQsviaclockfromamasterDAQLabel.Text = {'Sync DAQs via clock '; 'from a master DAQ'};
            app.PFIchannelLabel_2 = uilabel(app.ClockingTab);
            app.PFIchannelLabel_2.Text = 'PFI channel #';
            app.SepbyLabel = uilabel(app.UIFigure);
            app.SepbyLabel.Text = 'Sep. by:';
            app.SepbyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end